classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ImageProcessDropDown       matlab.ui.control.DropDown
        ImageProcessDropDownLabel  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Button                     matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage              % Will hold the original image data
        ProcessedImage             % Will hold the processed image data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('C:\Users\raval\OneDrive\Desktop\MATLAB\Projects\ImageProcessingToolboxProject\functions');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg', 'Images (*.jpg, *.png, *.jpeg)'});
            if isequal(file, 0)
                % User pressed cancel or closed the dialog
                return;
            else
                % Read and display the image
                fullFileName = fullfile(path, file);
                app.OriginalImage = imread(fullFileName);
                imshow(app.OriginalImage, 'Parent', app.UIAxes);
            end
        end

        % Value changed function: ImageProcessDropDown
        function ImageProcessDropDownValueChanged(app, event)
          value = app.ImageProcessDropDown.Value;
            if isempty(app.OriginalImage)
                return; % If no image is loaded, do nothing
            end
            switch value
                case 'Smooth'
                    % Call your smoothing function directly
                    app.ProcessedImage = smooth_image(app.OriginalImage);
                case 'Sharpen'
                    % Call your sharpening function directly
                    app.ProcessedImage = sharpen_image(app.OriginalImage);
                case 'Edge Detection'
                    % Call your edge detection function directly
                    app.ProcessedImage = edge_detection(app.OriginalImage);
            end
            % Update the display
            imshow(app.ProcessedImage, 'Parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Label = uilabel(app.UIFigure);
            app.ImageProcessDropDownLabel = uilabel(app.UIFigure);
            app.ImageProcessDropDownLabel.Text = 'Image Process';
            app.ImageProcessDropDown = uidropdown(app.UIFigure);
            app.ImageProcessDropDown.Items = {'Smooth', 'Sharpen', 'Edge Detection'};
            app.ImageProcessDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageProcessDropDownValueChanged, true);
            app.ImageProcessDropDown.Value = 'Smooth';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end